classdef Final_TestBed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HerramientadeCVparaRobticaSwarmUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalibracinTab                   matlab.ui.container.Tab
        btnCalibrar                     matlab.ui.control.Button
        btnConnectar                    matlab.ui.control.Button
        dropdownCamara                  matlab.ui.control.DropDown
        lblCamara                       matlab.ui.control.Label
        btnSeleccionManual              matlab.ui.control.Button
        imgLogo2                        matlab.ui.control.Image
        btnSaveCalli                    matlab.ui.control.Button
        lblErrorCalli                   matlab.ui.control.Label
        btnLoadCalli                    matlab.ui.control.Button
        TomadePoseTab                   matlab.ui.container.Tab
        btnPararTomadePose              matlab.ui.control.Button
        imgLogo1                        matlab.ui.control.Image
        btnTomadePose                   matlab.ui.control.Button
        lblDebug                        matlab.ui.control.Label
        chkboxBucle                     matlab.ui.control.CheckBox
        chkboxGuardarTiempos            matlab.ui.control.CheckBox
        GeneracindeCodigosTab           matlab.ui.container.Tab
        NmerodecdigoEditFieldLabel      matlab.ui.control.Label
        txtfldNumerodecodigo            matlab.ui.control.NumericEditField
        NombredelArchivoEditFieldLabel  matlab.ui.control.Label
        txtfldNombredelArchivo          matlab.ui.control.EditField
        btnGenerarCodigo                matlab.ui.control.Button
        btnGuardarCodigo                matlab.ui.control.Button
        lblNota                         matlab.ui.control.Label
        lblerrorGeneracion              matlab.ui.control.Label
        lblLogo3                        matlab.ui.control.Image
    end


    properties (Access = public)
        nombreCamara = '';      % Nombre de la cámara web a utilizar
        camara;                 % Cámara a utilizar
        tform;                  % Transformada de la mesa para el área desada
        outviewref;             % Formato de salida luego de transformada
        funcionando = false;    % Variable que controla el bucle de la toma de pose
        handleCodigo;           % Handle de figura para el despliegue de código
        handlePose;             % Handle de figura para el despliegue de pose
        manual_points;          % Puntos si se selecciona calibración manual
        manual = false;         % Variable que define si se seleccionó calibración manual
        obstacleMask;           % Imagen de la máscara de objetos
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            camList = webcamlist;
            app.dropdownCamara.Items = camList';
        end

        % Value changed function: dropdownCamara
        function CamaraDropDownValueChanged(app, event)
            app.nombreCamara = app.dropdownCamara.Value;
            camList = webcamlist;
            app.dropdownCamara.Items = camList';
            app.btnConnectar.Enable = true;
        end

        % Button pushed function: btnConnectar
        function btnConnectarPushed(app, event)
            %app.camara =  webcam('USB');
            app.camara =  webcam(app.nombreCamara);
            resolutions = app.camara.AvailableResolutions;
            app.camara.Resolution = string(resolutions(end));
            app.btnCalibrar.Enable = true;
            app.btnSeleccionManual.Enable = true;
        end

        % Button pushed function: btnCalibrar
        function btnCalibrarPushed(app, event)

            I = snapshot(app.camara);
            fixed_points=[0 0; size(I,2) 0; size(I,2) size(I,1); 0 size(I,1)];
            moving_points = [];
            if(app.manual)
                moving_points = app.manual_points;
            else
                [corners, ~, ~] = findCorners(I);

                fixed_points=[0 0; size(I,2) 0; size(I,2) size(I,1); 0 size(I,1)]; %puntos iniciales en la imagen original
                [moving_points] = orderCorners(corners(1:4,:), fixed_points);
            end
            app.tform = fitgeotrans(moving_points,fixed_points,'projective');
            app.outviewref = imref2d(size(I));
            Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
            
            %% Obstaculos
            CalliBW = rgb2gray(Itrans);
            CalliBW = edge(CalliBW,'Canny',0.1);
            CalliBW = logical(iclose(CalliBW,kcircle(10)));
            filledImage = imfill(CalliBW, 'holes');
            filledImage = logical(iopen(filledImage,kcircle(20)));
            
            app.obstacleMask = imcomplement(filledImage);
            figure(1); clf
            subplot(1, 2, 1)
            imshow(Itrans)
            title('Imagen Calibrada','FontWeight','normal')
            subplot(1, 2, 2)
            imshow(app.obstacleMask)
            title('Máscara de Obstáculos encontrados','FontWeight','normal')
            app.btnTomadePose.Enable = true;
            app.btnSaveCalli.Enable = true;
        end

        % Button pushed function: btnTomadePose
        function btnTomadePosePushed(app, event)
            app.btnPararTomadePose.Enable = true;
            app.funcionando = true;
            pivotThresh = 140; highThresh = 40;
            rad = 5*ones(9, 1);
            
            figure(1);
            self.handlePose = gca;
            title('Pose de códigos','FontWeight','normal')
            set(self.handlePose,'NextPlot','replacechildren');
            while(app.funcionando)
                pause(0.001)
                tic;
                I = snapshot(app.camara);
                Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
                CalliBW = rgb2gray(Itrans);
                CalliBW = edge(CalliBW,'Canny',0.5);
                CalliBW = logical(iclose(CalliBW,kcircle(2)));
                app.obstacleMask = logical(iopen(app.obstacleMask,kcircle(10))); 
                CalliBW = logical(app.obstacleMask.*CalliBW);
                figure(42)
                subplot(1,2,1)
                imshow(CalliBW)
                subplot(1,2,2)
                imshow(app.obstacleMask)
                s = regionprops(CalliBW, 'Centroid');
                centroids = cat(1, s.Centroid);
                centroids([1 2],:) = [];
                centroids([length(centroids)-1 length(centroids)],:) = [];
                
%                 for i = 1:length(centroids)
%                     viscircles(centroids(i,:), 10,'Color','Blue');
%                 end
                
                Itrans = rgb2gray(Itrans);
                [contours, lengths] = getContours(CalliBW);
                for i = length(lengths):-1:1
                    if(lengths(i) < 10)
                        remove(contours,string(i))
                        lengths(i) = [];
                    end
                end
                app.lblDebug.Text = string(length(contours));
                NumericIDs = zeros(1, floor(length(contours)/2) );
                Angles= zeros(1, floor(length(contours)/2) );
                
                for i = 1:length(contours)
                    if(mod(i, 2) ~= 0)
                        
                        angle = atan2(centroids(i,2)-centroids(i+1,2), centroids(i+1,1)-centroids(i,1));
                        angle = rad2deg(angle);
                        if(angle < 0)
                            angle = angle + 360;
                        end
                        angle = angle - 45;
                        Angles((i+1)/2) = angle;
                        
                        fixed_points=[0 0; size(I,1) 0; size(I,1) size(I,1); 0 size(I,1)]; %puntos iniciales en la imagen original
                        moving_points = contours(num2str(i));
                
                        codeTForm = fitgeotrans(moving_points,fixed_points,'projective');
                
                        codeOutViewRef = imref2d([size(Itrans,1), size(Itrans,1)]);
                        code = imwarp(Itrans, codeTForm, 'OutputView', codeOutViewRef);
                        corners = [1/4, 1/4; 2/4, 1/4; 3/4, 1/4;
                                   1/4, 2/4; 2/4, 2/4; 3/4, 2/4;
                                   1/4, 3/4; 2/4, 3/4; 3/4, 3/4;].*flip(size(code));
                        
                        if(code(corners(3,2), corners(3,1)) > pivotThresh)
                            code = imrotate(code, 90);
                        elseif(code(corners(7,2), corners(7,1)) > pivotThresh)
                            code = imrotate(code, -90);
                        elseif(code(corners(9,2), corners(9,1)) > pivotThresh)
                            code = imrotate(code, 180);
                        end
                        NumericID = zeros(1, 8);
                        for k = 1:length(corners)-1
                            if(code(corners(k+1,2), corners(k+1,1)) > highThresh)
                                NumericID(k) = 1;
                            else
                                NumericID(k) = 0;
                            end
                        end
                        NumericID = bi2de(NumericID, 'right-msb');
                        NumericIDs((i+1)/2) = NumericID;
                        
%                         figure
%                         imshow(code)
%                         text(0, 0, num2str(NumericID));
%                         NumericIDs((i+1)/2) = NumericID;
%                          dots = [];
%                          for k = 1:length(corners)
%                              dots = [dots;code(corners(k, 2), corners(k,1))];
%                             viscircles(corners(k,:), rad(k),'EdgeColor','b');
%                          end
%                          dots
%                         viscircles(corners, rad,'EdgeColor','b');
                    end
                end
                
                figure(1);
                imshow(Itrans, 'parent', self.handlePose)
                hold on
                for i = 1:length(contours)
                    if(mod(i, 2) ~= 0)
                        text(centroids(i,1), centroids(i,2)+30, strcat('ID : ', num2str(NumericIDs((i+1)/2))),'Color','red','FontSize',14)
                        text(centroids(i,1), centroids(i,2)+60, strcat('Ángulo : ', num2str(Angles((i+1)/2))), 'Color','red','FontSize',14)
                        text(centroids(i,1), centroids(i,2)+90, strcat('Centroide : (', num2str(centroids(i,1)), ',', num2str(centroids(i,2)), ')'), 'Color','red','FontSize',14)
                        viscircles(centroids(i,:), 5);
                    end
                end

                time = toc;
                if(app.chkboxGuardarTiempos.Value)
                    load('Tiempos.mat', 'times')
                    times = [times, time];
                    save('Tiempos.mat', 'times')
                    delete('times');
                end
                if(~app.chkboxBucle.Value)
                    app.funcionando = false;
                end
            end
        end

        % Button pushed function: btnPararTomadePose
        function btnPararTomadePosePushed(app, event)
           app.funcionando = false
           app.lblDebug.Text = 'Programa Parado';
        end

        % Button pushed function: btnGenerarCodigo
        function btnGenerarCodigoPushed(app, event)
            codigoID = app.txtfldNumerodecodigo.Value;
            codigo = CreadorCodigos(codigoID);
            figure(1); clf
            app.handleCodigo = gcf;
            imshow(codigo);
        end

        % Button pushed function: btnGuardarCodigo
        function btnGuardarCodigoPushed(app, event)
            saveas(app.handleCodigo, strcat(app.txtfldNombredelArchivo.Value,'.jpg'));
            app.lblerrorGeneracion.Text = {'Código','Guardado'};
        end

        % Button pushed function: btnSeleccionManual
        function btnSeleccionManualPushed(app, event)
            app.manual = true;
            I = snapshot(app.camara);
            [app.manual_points, ~] = cpselect(I, I,'Wait',true);
        end

        % Button pushed function: btnLoadCalli
        function btnLoadCalliButtonPushed(app, event)
            load('Datos.mat','obstacleMask', 'tform', 'outviewref', 'camara')
            app.lblErrorCalli.Text = 'Datos Recuperados de "Datos.mat"';
            app.obstacleMask = obstacleMask;
            app.tform = tform;
            app.outviewref = outviewref;
            app.camara = camara;
            
            I = snapshot(app.camara);
            Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
            
            figure(1); clf
            subplot(1, 2, 1)
            imshow(Itrans)
            title('Imagen Calibrada','FontWeight','normal')
            subplot(1, 2, 2)
            imshow(app.obstacleMask)
            title('Máscara de Obstáculos encontrados','FontWeight','normal')
            app.btnTomadePose.Enable = true;
        end

        % Button pushed function: btnSaveCalli
        function btnSaveCalliButtonPushed(app, event)
            obstacleMask = app.obstacleMask;
            tform = app.tform;
            outviewref = app.outviewref;
            camara = app.camara;
            save('Datos.mat','obstacleMask', 'tform', 'outviewref', 'camara')
            app.lblErrorCalli.Text = 'Datos Guardados en "Datos.mat"';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HerramientadeCVparaRobticaSwarmUIFigure = uifigure('Visible', 'off');
            app.HerramientadeCVparaRobticaSwarmUIFigure.Name = 'Herramienta de CV para Robótica Swarm';
            app.HerramientadeCVparaRobticaSwarmUIFigure.Icon = 'logo002.png';
            app.TabGroup = uitabgroup(app.HerramientadeCVparaRobticaSwarmUIFigure);
            app.CalibracinTab = uitab(app.TabGroup);
            app.CalibracinTab.Title = 'Calibración';
            app.btnCalibrar = uibutton(app.CalibracinTab, 'push');
            app.btnCalibrar.ButtonPushedFcn = createCallbackFcn(app, @btnCalibrarPushed, true);
            app.btnCalibrar.Enable = 'off';
            app.btnCalibrar.Text = 'Calibrar';
            app.btnConnectar = uibutton(app.CalibracinTab, 'push');
            app.btnConnectar.ButtonPushedFcn = createCallbackFcn(app, @btnConnectarPushed, true);
            app.btnConnectar.Text = 'Connectar';
            app.dropdownCamara = uidropdown(app.CalibracinTab);
            app.dropdownCamara.ValueChangedFcn = createCallbackFcn(app, @CamaraDropDownValueChanged, true);
            app.lblCamara = uilabel(app.CalibracinTab);
            app.lblCamara.Text = 'Cámara Web';
            app.btnSeleccionManual = uibutton(app.CalibracinTab, 'push');
            app.btnSeleccionManual.ButtonPushedFcn = createCallbackFcn(app, @btnSeleccionManualPushed, true);
            app.btnSeleccionManual.Enable = 'off';
            app.btnSeleccionManual.Text = 'Selección Manual';
            app.imgLogo2 = uiimage(app.CalibracinTab);
            app.imgLogo2.ImageSource = 'logo002.png';
            app.btnSaveCalli = uibutton(app.CalibracinTab, 'push');
            app.btnSaveCalli.ButtonPushedFcn = createCallbackFcn(app, @btnSaveCalliButtonPushed, true);
            app.btnSaveCalli.Enable = 'off';
            app.btnSaveCalli.Text = {'Guardar última '; 'calibración'; ''};
            app.lblErrorCalli = uilabel(app.CalibracinTab);
            app.lblErrorCalli.Text = '';
            app.btnLoadCalli = uibutton(app.CalibracinTab, 'push');
            app.btnLoadCalli.ButtonPushedFcn = createCallbackFcn(app, @btnLoadCalliButtonPushed, true);
            app.btnLoadCalli.Text = {'Cargar última '; 'calibración'; ''};
            app.TomadePoseTab = uitab(app.TabGroup);
            app.TomadePoseTab.Title = 'Toma de Pose';
            app.btnPararTomadePose = uibutton(app.TomadePoseTab, 'push');
            app.btnPararTomadePose.ButtonPushedFcn = createCallbackFcn(app, @btnPararTomadePosePushed, true);
            app.btnPararTomadePose.Enable = 'off';
            app.btnPararTomadePose.Text = 'Parar Toma de Pose';
            app.imgLogo1 = uiimage(app.TomadePoseTab);
            app.imgLogo1.ImageSource = 'logo002.png';
            app.btnTomadePose = uibutton(app.TomadePoseTab, 'push');
            app.btnTomadePose.ButtonPushedFcn = createCallbackFcn(app, @btnTomadePosePushed, true);
            app.btnTomadePose.Enable = 'off';
            app.btnTomadePose.Text = 'Toma de Pose';
            app.lblDebug = uilabel(app.TomadePoseTab);
            app.lblDebug.Text = '';
            app.chkboxBucle = uicheckbox(app.TomadePoseTab);
            app.chkboxBucle.Text = 'Bucle';
            app.chkboxBucle.Value = true;
            app.chkboxGuardarTiempos = uicheckbox(app.TomadePoseTab);
            app.chkboxGuardarTiempos.Text = {'Guardar Tiempos'; ''};
            app.GeneracindeCodigosTab = uitab(app.TabGroup);
            app.GeneracindeCodigosTab.Title = 'Generación de Códigos';
            app.NmerodecdigoEditFieldLabel = uilabel(app.GeneracindeCodigosTab);
            app.NmerodecdigoEditFieldLabel.Text = 'Número de código';
            app.txtfldNumerodecodigo = uieditfield(app.GeneracindeCodigosTab, 'numeric');
            app.txtfldNumerodecodigo.Limits = [0 255];
            app.NombredelArchivoEditFieldLabel = uilabel(app.GeneracindeCodigosTab);
            app.NombredelArchivoEditFieldLabel.Text = 'Nombre del Archivo';
            app.txtfldNombredelArchivo = uieditfield(app.GeneracindeCodigosTab, 'text');
            app.txtfldNombredelArchivo.Value = 'Codigo_generado';
            app.btnGenerarCodigo = uibutton(app.GeneracindeCodigosTab, 'push');
            app.btnGenerarCodigo.ButtonPushedFcn = createCallbackFcn(app, @btnGenerarCodigoPushed, true);
            app.btnGenerarCodigo.Text = 'Generar Código';
            app.btnGuardarCodigo = uibutton(app.GeneracindeCodigosTab, 'push');
            app.btnGuardarCodigo.ButtonPushedFcn = createCallbackFcn(app, @btnGuardarCodigoPushed, true);
            app.btnGuardarCodigo.Text = 'Guardar Código';
            app.lblNota = uilabel(app.GeneracindeCodigosTab);
            app.lblNota.Text = 'El archivo se guardará en formato jpg';
            app.lblerrorGeneracion = uilabel(app.GeneracindeCodigosTab);
            app.lblerrorGeneracion.Text = '';
            app.lblLogo3 = uiimage(app.GeneracindeCodigosTab);
            app.lblLogo3.ImageSource = 'logo002.png';
            % Show the figure after all components are created
            app.HerramientadeCVparaRobticaSwarmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_TestBed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HerramientadeCVparaRobticaSwarmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HerramientadeCVparaRobticaSwarmUIFigure)
        end
    end
end